classdef PlantEarthSegApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu_File                    matlab.ui.container.Menu
        Menu_APP_Exit                matlab.ui.container.Menu
        Menu_Reset                   matlab.ui.container.Menu
        Menu_Clear_Tab_figures       matlab.ui.container.Menu
        Menu_Clear_all_variables     matlab.ui.container.Menu
        Menu_About                   matlab.ui.container.Menu
        Menu_APP_Info                matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        PlantGroundSeg               matlab.ui.container.Tab
        ParametersPanel              matlab.ui.container.Panel
        HeightOffsetEditField        matlab.ui.control.NumericEditField
        HeightOffsetLabel            matlab.ui.control.Label
        EpsMultiplierEditField       matlab.ui.control.NumericEditField
        EpsMultiplierLabel           matlab.ui.control.Label
        LayerHeightEditField         matlab.ui.control.NumericEditField
        LayerHeightLabel             matlab.ui.control.Label
        StdThreshEditField           matlab.ui.control.NumericEditField
        StdThreshLabel               matlab.ui.control.Label
        MeanKEditField               matlab.ui.control.NumericEditField
        MeanKEditFieldLabel          matlab.ui.control.Label
        GridSizeEditField            matlab.ui.control.NumericEditField
        GridSizeEditFieldLabel       matlab.ui.control.Label
        VisualizationPanel           matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        SegmentedAxes                matlab.ui.control.UIAxes
        ClusteredAxes                matlab.ui.control.UIAxes
        TargetLayerAxes              matlab.ui.control.UIAxes
        LayeredAxes                  matlab.ui.control.UIAxes
        PreprocessedAxes             matlab.ui.control.UIAxes
        RawAxes                      matlab.ui.control.UIAxes
        FunctionPanel                matlab.ui.container.Panel
        OneKeyProcessButton          matlab.ui.control.Button
        Lamp_save                    matlab.ui.control.Lamp
        Lamp_seg                     matlab.ui.control.Lamp
        Lamp_cluster                 matlab.ui.control.Lamp
        Lamp_layer                   matlab.ui.control.Lamp
        Lamp_preprocess              matlab.ui.control.Lamp
        Lamp_load                    matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        SavePlantsButton             matlab.ui.control.Button
        SegmentButton                matlab.ui.control.Button
        ClusterButton                matlab.ui.control.Button
        LayerButton                  matlab.ui.control.Button
        PreprocessButton             matlab.ui.control.Button
        OpenFileButton               matlab.ui.control.Button
        Cluster2Plant                matlab.ui.container.Tab
        OpenInResourceWindow         matlab.ui.control.Button
        ChangeDirButton              matlab.ui.control.Button
        TextArea_workDir             matlab.ui.control.TextArea
        Label_3                      matlab.ui.control.Label
        ParametersPanel_2            matlab.ui.container.Panel
        Y4X603115Y14XLabel           matlab.ui.control.Label
        MeanKEditField_2             matlab.ui.control.NumericEditField
        MeanKEditField_2Label        matlab.ui.control.Label
        GridSizeEditField_2          matlab.ui.control.NumericEditField
        GridSizeEditField_2Label     matlab.ui.control.Label
        VisualizationPanel_2         matlab.ui.container.Panel
        GridLayout_2                 matlab.ui.container.GridLayout
        Axes_BoundingBox             matlab.ui.control.UIAxes
        Axes_ClusterPicture          matlab.ui.control.UIAxes
        Axes_ProjectPicture          matlab.ui.control.UIAxes
        Axes_RawPlants               matlab.ui.control.UIAxes
        FunctionPanel_2              matlab.ui.container.Panel
        segSwitch                    matlab.ui.control.Switch
        Label                        matlab.ui.control.Label
        Lamp_saveplants              matlab.ui.control.Lamp
        Lamp_bounding_box            matlab.ui.control.Lamp
        Lamp_canny_cluster           matlab.ui.control.Lamp
        Lamp_project                 matlab.ui.control.Lamp
        Lamp_load_2                  matlab.ui.control.Lamp
        StatusLabel_2                matlab.ui.control.Label
        BoundingBoxButton            matlab.ui.control.Button
        CannyBlurClusterButton       matlab.ui.control.Button
        ProjectToZPlantButton        matlab.ui.control.Button
        LoadCornDataButton           matlab.ui.control.Button
        SteamLeafSeg                 matlab.ui.container.Tab
        Panel3                       matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Axes_plant_with_types        matlab.ui.control.UIAxes
        Axes_plantSkelon_types       matlab.ui.control.UIAxes
        Axes_plantSkelon             matlab.ui.control.UIAxes
        Axes_RawPlant                matlab.ui.control.UIAxes
        Panel2                       matlab.ui.container.Panel
        UserEditSkelonButton         matlab.ui.control.Button
        SaveSkelonButton             matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        DropDown                     matlab.ui.control.DropDown
        Label_2                      matlab.ui.control.Label
        ClassifyPlantPointsButton    matlab.ui.control.Button
        ClassifySkelonButton         matlab.ui.control.Button
        CalculateSkelonButton        matlab.ui.control.Button
        LoadSingleCornPointsButton   matlab.ui.control.Button
        Tab                          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        TextArea_organView           matlab.ui.control.TextArea
        TextArea_plantOverview       matlab.ui.control.TextArea
        GridLayout5                  matlab.ui.container.GridLayout
        Button_LoadPlantSegInfo      matlab.ui.control.Button
        DropDown_SelectOrgan         matlab.ui.control.DropDown
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        UIAxes_PlantInfo             matlab.ui.control.UIAxes
        OtherTools                   matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        Windows1011Matlab2022bLabel  matlab.ui.control.Label
        SR1553104Label               matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        HitMaize3DSegLabforPhenotypeSegmentationandLabelingVersion10  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    properties (Access = private)
        RawPointCloud     % 原始点云
        PreprocessedCloud % 预处理点云
        LayeredData       % 分层数据
        TargetLayerCloud  % 目标层点云
        ClusterCenters    % 聚类中心
        NumClusters       % 聚类数
        TargetLayerIdx    % 目标层索引
        SegmentedLabels   % 分割标签
        plantsPoints      % 所有植株点云数组

        img_range         % 原始植株点云的分布范围[x,y,z,width,height]
        binaryXYImg       % 投影的XY平面二值图像
        plantClosedEdge   % 投影图像的边界范围
        objects_name = {}; % 存储植株标签信息
        work_dir          % 工作目录
        organNames        % 器官名称
    end
    
    % 自定义私有函数
    methods (Access = private)
        function [finalSortedData, numFullGroups, remainingNum] = seg_box(~, data_info, groupSize)
        
            N = size(data_info, 1);

            sortedData = sortrows(data_info, 1);
            numFullGroups = floor(N / groupSize);
            remainingData = sortedData((numFullGroups * groupSize + 1):end, :);

            finalSortedData = zeros(N, size(data_info, 2));
            idx = 1;
            for i = 1:numFullGroups
                groupIdx = (i-1)*groupSize + (1:groupSize);
                groupData = sortedData(groupIdx, :);
                sortedGroup = sortrows(groupData, 2, 'descend');
                finalSortedData(idx:idx+3, :) = sortedGroup;
                idx = idx + groupSize;
            end
            
            remainingNum = N - numFullGroups * groupSize;
            if remainingNum > 0
                remainingDataSorted = sortrows(remainingData, 2, 'descend');
                
                finalSortedData(idx + (1:remainingNum) -1, :) = remainingDataSorted;
            end
        end

        % 打开文件并更新app.workDir属性
        function fullFilePath = openFile(app)
            % 检查 app.work_dir 是否为空
            if isempty(app.work_dir)
                startPath = pwd;
            else
                startPath = app.work_dir;
            end
    
            [file, path] = uigetfile({'*.ply'}, '选择文件', char(startPath));
    
            % 检查用户是否取消文件选择
            if isempty(file)
                fullFilePath = ''; % 返回空字符串表示取消
                return;
            end
    
            app.work_dir = path;
    
            fullFilePath = fullfile(path, file);
        end

        function ring = compute_point_point_ring(~, pts, k, index)
            npts = size(pts,1);
            ring = cell(npts,1);

            if nargin < 4 || isempty(index)
                kdtree = kdtree_build(pts);     % kdtree,用来找k近邻
                index = zeros(npts, k);
                for i = 1:npts
                    index(i,:)  = kdtree_k_nearest_neighbors(kdtree,pts(i,:),k)';
                end
            end
            
            for i = 1:npts  
                neighbor = pts(index(i,:),:);
                [coefs, ~, ~] = pca(neighbor);
                x = [neighbor * coefs(:, 1), neighbor * coefs(:, 2)];

                TRI = delaunayn(x);
                
                [row, ~] = find(TRI == 1);
                temp = TRI(row,:);
                
                temp = sort(temp,2);
                temp = temp(:,2:end);
                
                % 找一环的第一个顶点：如果有出现一次的顶点，视为起点，否则任取一个
                x = temp(:);
                x = sort(x);
                d = diff([x; max(x)+1]);
                count = diff([0; find(d ~= 0)]); % 每个数字出现的次数
                y =[x(d ~= 0) count];
                n_sorted_index = size(y,1);
                start = find(count==1);
                if ~isempty(start) % 如果有只出现一次的顶点
                    want_to_find = y(start(1),1);
                else
                    want_to_find = temp(1,1);
                    n_sorted_index = n_sorted_index+1; % 首位是封闭的环
                end
                
                j = 0;    
                sorted_index = zeros(1,n_sorted_index);
                while j < n_sorted_index
                    j = j+1;
                    sorted_index(j) = want_to_find;
                    [row,col] = find(temp == want_to_find);
                    if ~isempty(col)
                        if col(1) == 1
                            want_to_find = temp(row(1),2);
                            temp(row(1),2) = -1;
                        else
                            want_to_find = temp(row(1),1);
                            temp(row(1),1) = -1;
                        end    
                    end
                end
                
                neighbor_index = index(i,sorted_index);
                
                % 首位为端点，如果邻域面是封闭的，则首位数字相同，否则不同
                ring{i} = neighbor_index;
            end
            
            if exist('kdtree', 'var')
                kdtree_delete(kdtree);
            end
        end

        %% one_ring_size
        function ms = one_ring_size(~, pts, rings, type)
            n = size(pts,1);
            ms = zeros(n,1);
            switch type
                case 1
                    parfor i = 1:n
                        ring = rings{i};    
                        tmp = repmat(pts(i,:), length(ring), 1) - pts(ring, :);
                        ms(i) = min(sum(tmp .^ 2, 2) .^ 0.5);
                    end
                case 2
                    parfor i = 1:n
                        ring = rings{i};    
                        tmp = repmat(pts(i,:), length(ring), 1) - pts(ring, :);
                        ms(i) = mean(sum(tmp .^ 2, 2) .^ 0.5);
                    end                    
                case 3
                    parfor i = 1:n
                        ring = rings{i};    
                        tmp = repmat(pts(i,:), length(ring), 1) - pts(ring,:);
                        ms(i) = max(sum(tmp .^ 2, 2) .^ 0.5);
                    end                    
           end
        end
        
        %% compute_point_weight_dcp
        function W = compute_point_weight_dcp(~, points, rings)
            n = length(points);
            W = sparse(n,n);
            for i = 1:n
                ring = rings{i};
            
                tmp = size(ring,2)-1;
                for ii = 1: tmp
                    j = ring(ii); k = ring(ii+1);
                    vi = points(i,:);
                    vj = points(j,:);
                    vk = points(k,:);
                    
                    % new % Oscar08 use this 
                    u = vk-vi; v = vk-vj;
                    cot1 = dot(u,v)/norm(cross(u,v));
                    W(i,j) = W(i,j) + cot1;
                    u = vj-vi; v = vj-vk;
                    cot2 = dot(u,v)/norm(cross(u,v));
                    W(i,k) = W(i,k) + cot2;
                end
                
                tmp = abs(sum(W(i, :)));
                if tmp>10000
                    W(i, :) = W(i, :) * 10000 / tmp;
                end
            end
        end

        function [spls, spls_adj, corresp] = rosa_lineextract(~, cpts, rings, sample_radius, bUpdateConnectivity)
            collapse_order = 1;
            
            if (bUpdateConnectivity)
                [spls, corresp] = farthest_sampling_by_sphere(cpts, sample_radius);
                spls_adj = connect_by_inherit_neigh(cpts, spls, corresp, rings);    
                [spls, spls_adj, corresp] = edge_collapse_update(cpts, spls, corresp, rings, spls_adj, collapse_order);
            else
                [spls, corresp] = farthest_sampling_by_sphere(cpts, sample_radius);
                spls_adj = connect_by_inherit_neigh(cpts, spls, corresp, rings);
                [spls, spls_adj, corresp] = edge_collapse(cpts, spls, corresp, rings, spls_adj, collapse_order);
            end
        end

        function updateCallback(~, editedPoints, editedTypes)
            % 这里可以根据编辑结果更新 UI、保存、重新绘图等
            disp('返回数据如下：');
            disp('编辑后的点：');
            disp(editedPoints(1:5,:)); % 示例：显示前5个点
            disp('编辑后的类型：');
            disp(editedTypes(1:5));    % 示例：显示前5个类型
    
            % 你也可以保存到 app 的属性中
            % app.MyUpdatedPoints = editedPoints;
            % app.MyUpdatedTypes = editedTypes;
    
            % 或者调用你自己的刷新函数，如：
            % updateMainPlot(app, editedPoints, editedTypes);
        end

        function [OBB] = find_OBB_mt(~, points, dir1Num, dir2Num, dir3Num, show_result)
            % FIND_OBB_MT 计算三维点云的有向包围盒（OBB）及其体素分割
            % 输入：
            %   points      - N×3 点云数据
            %   dir1Num~3Num- 在主轴方向上的体素划分数
            %   show_result - 是否可视化显示OBB结果
            % 输出：
            %   OBB         - 包含方向、尺寸、体素划分等信息的结构体
            
            % --- 主方向计算 ---
            [coeff, ~, score] = pca(points);     % coeff为方向向量，score为变换后坐标
            center = mean(points, 1);            % 中心点
            n = size(points, 1);
            dir1 = repmat(coeff(:,1)', [n 1]);
            dir2 = repmat(coeff(:,2)', [n 1]);
            dir3 = repmat(coeff(:,3)', [n 1]);
            
            % --- 投影点到主轴方向 ---
            displace = points - center;
            V1 = dot(displace, dir1, 2);
            V2 = dot(displace, dir2, 2);
            V3 = dot(displace, dir3, 2);
            
            minX = min(V1); maxX = max(V1);
            minY = min(V2); maxY = max(V2);
            minZ = min(V3); maxZ = max(V3);
            
            % --- 构造OBB基本信息 ---
            OBB.ExtentLen = [maxX - minX; maxY - minY; maxZ - minZ];
            OBB.Min = [minX; minY; minZ];
            OBB.Max = [maxX; maxY; maxZ];
            OBB.Principal = score;
            OBB.dir1 = dir1(1,:);
            OBB.dir2 = dir2(1,:);
            OBB.dir3 = dir3(1,:);
            OBB.center = center;
            
            % --- 包围盒八个顶点 ---
            OBB.p0 = minX*OBB.dir1 + minY*OBB.dir2 + minZ*OBB.dir3 + center;
            OBB.p1 = maxX*OBB.dir1 + minY*OBB.dir2 + minZ*OBB.dir3 + center;
            OBB.p2 = maxX*OBB.dir1 + maxY*OBB.dir2 + minZ*OBB.dir3 + center;
            OBB.p3 = minX*OBB.dir1 + maxY*OBB.dir2 + minZ*OBB.dir3 + center;
            OBB.p4 = minX*OBB.dir1 + minY*OBB.dir2 + maxZ*OBB.dir3 + center;
            OBB.p5 = maxX*OBB.dir1 + minY*OBB.dir2 + maxZ*OBB.dir3 + center;
            OBB.p6 = maxX*OBB.dir1 + maxY*OBB.dir2 + maxZ*OBB.dir3 + center;
            OBB.p7 = minX*OBB.dir1 + maxY*OBB.dir2 + maxZ*OBB.dir3 + center;
            
            % --- 初始化体素分割结构体 ---
            stepX = OBB.ExtentLen(1) / dir1Num;
            stepY = OBB.ExtentLen(2) / dir2Num;
            stepZ = OBB.ExtentLen(3) / dir3Num;
            
            template.Min = [0; 0; 0];
            template.Max = [0; 0; 0];
            template.Num = 0;
            template.Indices = [];
            
            OBB.partition = repmat(template, [dir1Num, dir2Num, dir3Num]);
            
            % --- 填充每个体素单元边界信息 ---
            for i = 1:dir1Num
                for j = 1:dir2Num
                    for k = 1:dir3Num
                        OBB.partition(i,j,k).Min = OBB.Min + [stepX*(i-1); stepY*(j-1); stepZ*(k-1)];
                        OBB.partition(i,j,k).Max = OBB.Min + [stepX*i; stepY*j; stepZ*k];
                    end
                end
            end
            
            % --- 每个点映射到体素单元中 ---
            Vpar1 = min(floor((V1 - OBB.Min(1)) / stepX) + 1, dir1Num);
            Vpar2 = min(floor((V2 - OBB.Min(2)) / stepY) + 1, dir2Num);
            Vpar3 = min(floor((V3 - OBB.Min(3)) / stepZ) + 1, dir3Num);
            
            for i = 1:n
                I1 = Vpar1(i);
                I2 = Vpar2(i);
                I3 = Vpar3(i);
                OBB.partition(I1, I2, I3).Num = OBB.partition(I1, I2, I3).Num + 1;
                OBB.partition(I1, I2, I3).Indices(end+1) = i;
            end
            
            % --- 统计含点体素数量 ---
            OBB.HasPointPartNum = sum(arrayfun(@(p) p.Num > 0, OBB.partition), 'all');
            OBB.dir1_ParNum = dir1Num;
            OBB.dir2_ParNum = dir2Num;
            OBB.dir3_ParNum = dir3Num;
            
            % --- 可视化结果（可选） ---
            if show_result
                figure;
                scatter3(points(:,1), points(:,2), points(:,3), 5, [0.3 0.3 0.3], 'filled'); hold on;
                for i = 1:dir1Num
                    for j = 1:dir2Num
                        for k = 1:dir3Num
                            part = OBB.partition(i,j,k);
                            if part.Num > 0
                                color = 'r';
                            else
                                color = 'g';
                            end
                            draw_obb_box(part.Min, part.Max, OBB.dir1, OBB.dir2, OBB.dir3, OBB.center, color);
                        end
                    end
                end
                axis equal; view(3); title('OBB + Voxel Partition');
            end
        end

        function [points, OBBs, OrgPoints, PhenoTraits, OrganTypes] = PhenotypicTrait(app, points, g_regions)
            PhenoTraits = [];     % 每个器官的参数记录
            OrganTypes = {};      % 类型标签 ('leaf', 'stem', 'plant')
            OBBs = {};            % 所有OBB结构体
            OrgPoints = {};       % 每个器官的点云
            
            minZ = min(points(:,3));
            organCount = numel(g_regions);
            
            % ---- 1. 提取整株OBB参数 ----
            PlantOBB = find_OBB_mt(app, points, 1, 1, 1, false);
            
            % Get stem direction for later comparison
            stemPoints = points(g_regions{1}, :);
            StemOBB = find_OBB_mt(app, stemPoints,1,1,1,false);
            stemAxis = StemOBB.dir1 / norm(StemOBB.dir1);
            
            len = PlantOBB.ExtentLen(1);
            wid = PlantOBB.ExtentLen(3);
            h_min = min(points(:,3)) - minZ;
            h_max = max(points(:,3)) - minZ;
            
            volume = prod(PlantOBB.ExtentLen);           % OBB体积
            pt_num = size(points, 1);               % 点数
            vol_density = pt_num / volume;          % 点密度
            
            % 植株XY投影尺寸和面积密度
            xy_proj = points(:,1:2);
            l_xy = range(xy_proj(:,1)); w_xy = range(xy_proj(:,2));
            res = min([l_xy, w_xy])/200;
            [Xg,Yg] = ndgrid(min(xy_proj(:,1)):res:max(xy_proj(:,1)), min(xy_proj(:,2)):res:max(xy_proj(:,2)));
            counts = histcounts2(xy_proj(:,1), xy_proj(:,2), Xg(:,1), Yg(1,:));
            pixel_area = nnz(counts);
            area_density = pixel_area / (l_xy * w_xy);
            
            % 茎秆主轴与XY夹角
            angle_stem_xy = acosd(abs(stemAxis(3)));
            
            % 冠层空间占用率
            plant_volume = prod(PlantOBB.ExtentLen);
            occu_ratio = size(points,1) / plant_volume;
            
            PhenoTraits(end+1,:) = [len, wid, h_min, h_max, vol_density, area_density, angle_stem_xy, occu_ratio, pt_num];
            OrganTypes{end+1} = 'plant';
            OBBs{end+1} = PlantOBB;
            OrgPoints{end+1} = points;
            
            % === 2. 提取茎秆和叶片的OBB参数 ===
            for i = 1:organCount
                indices = g_regions{i};
                organPts = points(indices, :);
            
                % 去噪
                organPts = pcdenoise(pointCloud(organPts), 'NumNeighbors', 8, 'Threshold', 1).Location;
                
                % 计算 OBB
                organOBB = find_OBB_mt(app, organPts, 1, 1, 1, false);
            
                % 提取形态参数
                len = organOBB.ExtentLen(1);          % 主轴方向（长度）
                wid = organOBB.ExtentLen(3);          % 最短轴方向（宽度）
                leaf_pos_hight = min(organPts(:,3)) - minZ;
                
                % 体积密度
                volume = prod(organOBB.ExtentLen);           % OBB体积
                pt_num = size(organPts, 1);            % 点数
                vol_density = pt_num / volume;          % 点密度
            
                % 面积密度
                leaf_main_axis = organOBB.dir1; v3 = organOBB.dir3;
            
                centered = organPts - organOBB.center;
                proj2D = centered * [leaf_main_axis(:), v3(:)];
                pixel_size = min(organOBB.ExtentLen(1:2))/100; % 自定义像素分辨率
                [Xedges, Yedges] = ndgrid(min(proj2D(:,1)):pixel_size:max(proj2D(:,1)), ...
                                          min(proj2D(:,2)):pixel_size:max(proj2D(:,2)));
                counts = histcounts2(proj2D(:,1), proj2D(:,2), Xedges(:,1), Yedges(1,:));
                area_density = nnz(counts) / (len * wid);       % 非0像素个数 / 面积
            
                % 起点-终点连线长度(基尖距)
                start_pt = organPts(1,:); end_pt = organPts(end,:);
                vec = end_pt - start_pt;
                seg_len = norm(vec);    % 向量的模长（长度）
            
            
                % 叶夹角是叶片主轴方向与茎秆方向之间的夹角。
                leaf_axis = leaf_main_axis / norm(leaf_main_axis);
                angle_leaf_stem = acosd(dot(leaf_axis, PlantOBB.dir1));
            
                % 叶仰角是叶片主轴方向与水平面之间的夹角
                % 水平面法向量 (假设Z轴垂直向上)
                N_horizontal = [0, 0, 1];
                leaf_elevation_angle = rad2deg(pi/2 - acos(dot(leaf_main_axis, N_horizontal)));
            
                % 叶片方位角：叶片在水平面上的朝向
                V_leaf_xy_proj = [leaf_main_axis(1), leaf_main_axis(2)];

                leaf_azimuth_angle = atan2d(V_leaf_xy_proj(2), V_leaf_xy_proj(1));
            
                % 角度标准化，范围在 [0, 360) 度
                if leaf_azimuth_angle < 0
                    leaf_azimuth_angle = leaf_azimuth_angle + 360;
                end
            
                % 汇总
                if i == 1
                    OrganTypes{end+1} = 'stem';
                else
                    OrganTypes{end+1} = 'leaf';
                end
            
                % 记录
                PhenoTraits(end+1,:) = [len, wid, vol_density, area_density, leaf_pos_hight, seg_len, angle_leaf_stem, leaf_elevation_angle, leaf_azimuth_angle];
                OBBs{end+1} = organOBB;
                OrgPoints{end+1} = organPts;
            end          
        end

        function draw_obb_box(app, minV, maxV, dir1, dir2, dir3, center, color)
            % 计算OBB的8个顶点
            p0 = minV(1)*dir1 + minV(2)*dir2 + minV(3)*dir3 + center;
            p1 = maxV(1)*dir1 + minV(2)*dir2 + minV(3)*dir3 + center;
            p2 = maxV(1)*dir1 + maxV(2)*dir2 + minV(3)*dir3 + center;
            p3 = minV(1)*dir1 + maxV(2)*dir2 + minV(3)*dir3 + center;
            p4 = minV(1)*dir1 + minV(2)*dir2 + maxV(3)*dir3 + center;
            p5 = maxV(1)*dir1 + minV(2)*dir2 + maxV(3)*dir3 + center;
            p6 = maxV(1)*dir1 + maxV(2)*dir2 + maxV(3)*dir3 + center;
            p7 = minV(1)*dir1 + maxV(2)*dir2 + maxV(3)*dir3 + center;
        
            edges = [p0;p1; p1;p2; p2;p3; p3;p0;
                     p4;p5; p5;p6; p6;p7; p7;p4;
                     p0;p4; p1;p5; p2;p6; p3;p7];
            for i = 1:2:size(edges,1)
                plot3(app.UIAxes_PlantInfo, edges(i:i+1,1), edges(i:i+1,2), edges(i:i+1,3), color, 'LineWidth', 1);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化界面
            app.UIFigure.Name = '玉米三维表型分割与标注平台 - V1.0';

            app.work_dir = pwd;

            app.FunctionPanel.Title = '功能区';
            app.ParametersPanel.Title = '参数区';
            app.VisualizationPanel.Title = '可视化区';

            app.FunctionPanel_2.Title = '功能区';
            app.ParametersPanel_2.Title = '参数区';
            app.VisualizationPanel_2.Title = '可视化区';

            app.OpenFileButton.Text = '打开点云文件';
            app.PreprocessButton.Text = '点云预处理';
            app.LayerButton.Text = '点云分层';
            app.ClusterButton.Text = '投影聚类';
            app.SegmentButton.Text = '生长分割';
            app.SavePlantsButton.Text = '保存植株点云';
            app.OneKeyProcessButton.Text = '一键分割';
            app.GridSizeEditField.Value = 0.02;
            app.MeanKEditField.Value = 20;

            app.ChangeDirButton.Text = '更改工作目录';
            app.TextArea_workDir.Value = app.work_dir;
            app.OpenInResourceWindow.Text = '打开目录';
            app.LoadCornDataButton.Text = '加载群体点云';
            app.ProjectToZPlantButton.Text = '投影点云至XY平面';
            app.CannyBlurClusterButton.Text = '划定边界范围';
            app.BoundingBoxButton.Text = '获取包围框与分割';

            app.LoadSingleCornPointsButton.Text = '加载标签点云';
            app.CalculateSkelonButton.Text = '计算点云骨架';
            app.ClassifySkelonButton.Text = '骨架结构分类';
            app.ClassifyPlantPointsButton.Text = '植株点云分类';
            app.SaveSkelonButton.Text = '保存骨架点云';

            app.RawPointCloud = [];
            app.PreprocessedCloud = [];
            app.LayeredData = struct();
            app.ClusterCenters = [];
            app.SegmentedLabels = [];
            app.TargetLayerIdx = [];
            app.plantsPoints = [];

            app.Lamp_load.Color = [0.8 0.8 0.8]; % 灰色
            app.Lamp_preprocess.Color = [0.8 0.8 0.8];
            app.Lamp_layer.Color = [0.8 0.8 0.8];
            app.Lamp_cluster.Color = [0.8 0.8 0.8];
            app.Lamp_seg.Color = [0.8 0.8 0.8];
            app.Lamp_save.Color = [0.8 0.8 0.8];

            app.Lamp_load_2.Color = [0.8 0.8 0.8];
            app.Lamp_project.Color  = [0.8 0.8 0.8];
            app.Lamp_canny_cluster.Color  = [0.8 0.8 0.8];
            app.Lamp_bounding_box.Color  = [0.8 0.8 0.8];
            app.Lamp_saveplants.Color  = [0.8 0.8 0.8];

            % 获取 App 所在目录
            appDir = fileparts(mfilename('fullpath'));
            
            % 假设 MEX 文件放在 appDir 下的 'mex' 子文件夹
            mexPath = fullfile(appDir, 'mex');
        
            % 添加路径
            addpath(mexPath);
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            % 初始化信号灯为灰色
            app.Lamp_load.Color = [0.8 0.8 0.8];
            try
                % 调用openFile属性函数
                fullFilePath = app.openFile();

                % 检查文件是否存在
                if ~exist(fullFilePath, 'file')
                    errordlg('用户取消文件选择或所选文件不存在', '错误');
                    return;
                end

                app.RawPointCloud = pcread(fullFilePath);
        
                % 检查点云是否有效
                if isempty(app.RawPointCloud)
                    errordlg('无效的点云数据', '错误');
                    return;
                end
        
                % 检查 axes 是否有效
                if isvalid(app.RawAxes)
                    % 使用 scatter3d 进行可视化
                    % 5 为点的大小，'b.' 为蓝色小点
                    scatter3(app.RawAxes, app.RawPointCloud.Location(:, 1), app.RawPointCloud.Location(:, 2), app.RawPointCloud.Location(:, 3), 5, 'b.');
                    title(app.RawAxes, '原始点云');
                else
                    errordlg('坐标轴无效', '错误');
                    return;
                end
        
                % 功能执行成功，将信号灯设置为绿色
                app.Lamp_load.Color = 'green';
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_load.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            % 初始化信号灯为灰色
            app.Lamp_preprocess.Color = [0.8 0.8 0.8];
        
            try
                % 检查原始点云是否为空
                if isempty(app.RawPointCloud)
                    errordlg('请先打开点云文件', '错误');
                    return;
                end
        
                % 获取参数值
                meanK = app.MeanKEditField.Value;
                stdThresh = 1; % 你可以根据需要调整这个值
                gridSize = app.GridSizeEditField.Value;
        
                % 执行点云预处理
                [~, inliers] = pcdenoise(app.RawPointCloud, 'NumNeighbors', meanK, 'Threshold', stdThresh);
                ptCloudClean = select(app.RawPointCloud, inliers);
                app.PreprocessedCloud = pcdownsample(ptCloudClean, 'gridAverage', gridSize);
        
                % 检查预处理后的点云是否有效
                if isempty(app.PreprocessedCloud)
                    errordlg('预处理后的点云为空', '错误');
                    return;
                end
        
                % 检查坐标轴是否有效
                if isvalid(app.PreprocessedAxes)
                    % 使用 scatter3 进行可视化
                    points = app.PreprocessedCloud.Location;
                    scatter3(app.PreprocessedAxes, points(:, 1), points(:, 2), points(:, 3), 1, 'green', 'filled');
                    title(app.PreprocessedAxes, '预处理点云(去噪+体素滤波)');
                else
                    errordlg('坐标轴无效', '错误');
                    return;
                end
        
                % 功能执行成功，将信号灯设置为绿色
                app.Lamp_preprocess.Color = 'green';
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_preprocess.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: LayerButton
        function LayerButtonPushed(app, event)
            % 初始化信号灯为灰色
            app.Lamp_layer.Color = [0.8 0.8 0.8];
            
            try
               % 检查原始点云是否为空
               if isempty(app.PreprocessedCloud)
                   errordlg('预处理点云为空，请先执行预处理！', '错误');
                   return; 
               end
               
               points = app.PreprocessedCloud.Location;
               zCoords = points(:, 3);
               zMin = min(zCoords);
               zMax = max(zCoords);
               layerHeight = 0.02;
               numLayers = ceil((zMax - zMin) / layerHeight);
               if numLayers < 1
                   error('层数计算错误，检查点云Z范围或层高');
               end
           
               % 分层并计算密度
               layerPoints = cell(numLayers, 1);
               layerDensities = zeros(numLayers, 1);
               for i = 1:numLayers
                   zLow = zMin + (i-1) * layerHeight;
                   zHigh = zMin + i * layerHeight;
                   idx = points(:, 3) >= zLow & points(:, 3) < zHigh;
                   layerPoints{i} = points(idx, :);
                   layerDensities(i) = size(layerPoints{i}, 1) / layerHeight;      % 密度 = 点数/层高
               end
           
               % 存储分层数据
               app.LayeredData = struct('Points', layerPoints, 'Densities', layerDensities, 'LayerHeight', layerHeight, 'ZMin', zMin);
           
               % 优化方法1：结合高度约束选择茎秆层
               stemHeightMid = zMin + (zMax - zMin) * 0.25;
               validLayerIdx = find(zMin + (0:numLayers-1) * layerHeight <= stemHeightMid);
               [~, minDensityIdx] = min(layerDensities(validLayerIdx));
               app.TargetLayerIdx = validLayerIdx(minDensityIdx);
               targetLayerPoints = layerPoints{app.TargetLayerIdx};
           
               if isempty(targetLayerPoints)
                    errordlg('目标层点云为空，请检查分层参数或点云数据，需有地面且点密度大于植株', '错误');
                    return;
               end
               
               app.TargetLayerCloud = targetLayerPoints;
               
               % 可视化
               % 检查坐标轴是否有效
               if isvalid(app.LayeredAxes)
                       % 计算层索引并限制范围
                       scatter3(app.LayeredAxes, points(:, 1), points(:, 2), points(:, 3), 1, 'green', 'filled');
                       hold(app.LayeredAxes, 'on');
                       
                       % 在原始点云中，可视化提取出来的特殊层
                       for i = 1:length(app.TargetLayerIdx)
                           scatter3(app.LayeredAxes, targetLayerPoints(:, 1), targetLayerPoints(:, 2), targetLayerPoints(:, 3), 15, 'filled');
                       end
                       title(app.LayeredAxes, '分层点云');
               else
                   errordlg('坐标轴无效', '错误');
                   return;
               end
               
               % 功能执行成功，将信号灯设置为绿色
               app.Lamp_layer.Color = 'green';
               
            catch ME
               errordlg(ME.message, '错误');
               app.Lamp_layer.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            % 初始化信号灯为灰色
            app.Lamp_cluster.Color = [0.8 0.8 0.8];
            
            try
                % 检查输入点云是否为空
                if isempty(app.TargetLayerCloud)
                    errordlg('分层点云为空，请先执行分层处理！', '错误');
                    return; 
                end
                
                targetLayerXY = app.TargetLayerCloud(:, 1:2);
                % 自适应DBSCAN参数
                distances = pdist2(targetLayerXY, targetLayerXY, 'euclidean', 'Smallest', 11);   % 每个点的11个最近邻距离
                avgNNDist = mean(distances(2:11, :), 'all');
                eps = avgNNDist * 0.5;
                localDensities = sum(distances < eps, 2);
                minPts = min(2, round(median(localDensities) * 0.03));
                clusterIdx = dbscan(targetLayerXY, eps, minPts);
                app.NumClusters = max(clusterIdx);
                app.ClusterCenters = zeros(app.NumClusters, 2);
                for i = 1:app.NumClusters
                    app.ClusterCenters(i,:) = mean(targetLayerXY(clusterIdx == i, :), 1);
                end
                    
                if isempty(app.ClusterCenters)
                     errordlg('目标层点云为空，请检查分层参数或点云数据', '错误');
                     return;
                end
                
                
                % % 可视化 XY 平面投影和聚类结果
                % 检查坐标轴是否有效
                if isvalid(app.TargetLayerAxes) && isvalid(app.ClusteredAxes)
                    colors = lines(app.NumClusters);        % 为每个簇分配不同颜色
                    for i = 1:app.NumClusters
                        clusterPoints = targetLayerXY(clusterIdx == i, :);
                        scatter(app.TargetLayerAxes, clusterPoints(:, 1), clusterPoints(:, 2), 10, colors(i, :), 'filled');         % 绘制聚类点
                        hold(app.TargetLayerAxes, 'on');
                        scatter(app.ClusteredAxes, app.ClusterCenters(i, 1), app.ClusterCenters(i, 2), 50, 'r', 'x', 'LineWidth', 2);       % 绘制聚类中心（红色X）
                        hold(app.ClusteredAxes, 'on');
                        % 显示标签信息
                        text(app.ClusteredAxes, app.ClusterCenters(i, 1), app.ClusterCenters(i, 2), int2str(i), 'Color', 'red', 'FontSize', 18);
                    end
                    title(app.TargetLayerAxes, '投影结果');
                    title(app.ClusteredAxes, '聚类结果');
                else
                    errordlg('坐标轴无效', '错误');
                    return;
                end
                
                % 功能执行成功，将信号灯设置为绿色
                app.Lamp_cluster.Color = 'green';
                    
            catch ME
                    errordlg(ME.message, '错误');
                    app.Lamp_cluster.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            % 初始化信号灯为灰色
            app.Lamp_seg.Color = [0.8 0.8 0.8];

            try
                if isempty(app.ClusterCenters)
                    errordlg('聚类点云为空，请先执行聚类处理！', '错误');
                    return; 
                end
    
                pts = app.PreprocessedCloud.Location;
    
                labels = ones(size(pts, 1), 1);
                z_Min = app.LayeredData.ZMin;
                layerHeight = app.LayeredData.LayerHeight;
                targetLayerLowerBound = z_Min + (app.TargetLayerIdx - 1) * layerHeight;
                labels(pts(:,3) > targetLayerLowerBound) = 2;
    
                % 将高于目标层下边界的点标记为植株
                plantMask = pts(:, 3) > targetLayerLowerBound;
                labels(plantMask) = 2;
    
                % 初始化剩余点池
                remainingIdx = find(~plantMask);
                remainingPoints = pts(remainingIdx, :);
                
    
                % 计算全局密度均值
                globalDensity = app.LayeredData.Densities;
                globalDensity = mean(globalDensity);
                
                % 定义立方体边长和密度差阈值
                cubeSide = 0.15; % 立方体边长（米，可调）
                densityDiffThresh = 0.1 * globalDensity;
                targetLayerXY = app.TargetLayerCloud(:, 1:2);
                for i = 1:max(0, app.NumClusters)
                    % 从目标层过滤后的点中找到聚类中心的最近点
                    seedXY = app.ClusterCenters(i, :);
                    seedIdxInLayer = knnsearch(targetLayerXY, seedXY, 'K', 1);
                    seedPoint = app.TargetLayerCloud(seedIdxInLayer, :);
                
                    % 初始化生长区域
                    currentPoints = seedPoint;
                    currentIdx = knnsearch(remainingPoints, seedPoint, 'K', 1);
                    visited = false(size(remainingPoints, 1), 1);
                    visited(currentIdx) = true;
                    labels(remainingIdx(currentIdx)) = 2;
                    
                    prevDensity = 0; % 初始上一密度
                    radius = cubeSide * 2 / 3; % 邻域半径（半个立方体边长）
                    
                    while ~isempty(currentPoints)
                        % 获取当前生长区域的最小Z值
                        currentZ = min(currentPoints(:, 3));
                        
                        % 邻域查找
                        [neighborsCell, ~] = rangesearch(remainingPoints, currentPoints, radius);
                        neighbors = unique([neighborsCell{:}]'); % 去重
                        
                        % 筛选Z值小于currentZ的点
                        downwardMask = remainingPoints(neighbors, 3) < currentZ & ~visited(neighbors);
                        newIdx = neighbors(downwardMask);
                        newPoints = remainingPoints(newIdx, :);
                        
                        if isempty(newPoints)
                            break; % 无向下点可添加，停止生长
                        end
                        
                        % 更新生长区域
                        currentPoints = [currentPoints; newPoints];
                        visited(newIdx) = true;
                        labels(remainingIdx(newIdx)) = 2;
                        
                        % 计算新添加点的局部密度
                        newPointsMin = min(newPoints, [], 1);
                        newPointsMax = max(newPoints, [], 1);
                        localVolume = prod(max(newPointsMax - newPointsMin, cubeSide)); % 动态体积
                        localDensity = size(newPoints, 1) / localVolume;
                        
                        % 计算密度差值
                        densityDiff = localDensity - prevDensity;
                        
                        % 判断密度变化
                        if densityDiff > densityDiffThresh
                            break;
                        end
                        prevDensity = localDensity;
                    end
                end
    
    
                % 可视化
                % 检查坐标轴是否有效
                if isvalid(app.SegmentedAxes)
                    app.SegmentedLabels = labels;
                    scatter3(app.SegmentedAxes, pts(labels == 1, 1), pts(labels == 1, 2), pts(labels == 1, 3), 5, [0 1 0]);   % 地面（绿色）
                    hold(app.SegmentedAxes, 'on');
                    scatter3(app.SegmentedAxes, pts(labels == 2, 1), pts(labels == 2, 2), pts(labels == 2, 3), 5, [1 0 0], "filled");   % 植株（红色）
        
                    title(app.SegmentedAxes, '植株与地面分割');
                else
                    errordlg('坐标轴无效', '错误');
                    return;
                end
    
                % 功能执行成功，将信号灯设置为绿色
                app.Lamp_seg.Color = 'green';
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_seg.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: SavePlantsButton
        function SavePlantsButtonPushed(app, event)
            % 初始化信号灯为灰色
            app.Lamp_save.Color = [0.8 0.8 0.8];
        
            try
                % 检查前置条件
                if isempty(app.SegmentedLabels) || isempty(app.PreprocessedCloud)
                    errordlg('分割结果或预处理点云未定义，请先完成分割', '错误');
                    return;
                end
        
                % 获取点云和标签
                points = app.PreprocessedCloud.Location;
                labels = app.SegmentedLabels;
        
                % 提取植株点云（labels == 2）
                app.plantsPoints = points(labels == 2, :);
                if isempty(app.plantsPoints)
                    errordlg('未检测到植株点云，无法保存', '错误');
                    return;
                end
        
                % 弹出保存对话框
                [file, path] = uiputfile('*.ply', '保存植株点云', 'plant_point_cloud.ply');
                if isequal(file, 0) % 用户取消保存
                    return;
                end
        
                % 创建 pointCloud 对象并保存
                plantsCloud = pointCloud(app.plantsPoints);
                pcwrite(plantsCloud, fullfile(path, file));
                disp(['植株点云已保存至: ', fullfile(path, file)]);
        
                % 功能执行成功，将信号灯设置为绿色
                app.Lamp_save.Color = 'green';
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_save.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: OneKeyProcessButton
        function OneKeyProcessButtonPushed(app, event)
            % 初始化信号灯
            app.Lamp_load.Color = [0.8 0.8 0.8]; % 灰色
            app.Lamp_preprocess.Color = [0.8 0.8 0.8];
            app.Lamp_layer.Color = [0.8 0.8 0.8];
            app.Lamp_cluster.Color = [0.8 0.8 0.8];
            app.Lamp_seg.Color = [0.8 0.8 0.8];
        
            try
                % 1. 打开文件
                OpenFileButtonPushed(app, event);
                if isempty(app.RawPointCloud), return; end % 如果打开文件失败，则停止执行
                app.Lamp_load.Color = 'green';
        
                % 2. 预处理
                PreprocessButtonPushed(app, event);
                if isempty(app.PreprocessedCloud), return; end % 如果预处理失败，则停止执行
                app.Lamp_preprocess.Color = 'green';

                % 3. 分层处理
                LayerButtonPushed(app, event);
                if isempty(app.TargetLayerCloud), return; end % 如果分类失败，则停止执行
                app.Lamp_layer.Color = 'green';

                % 4. 投影聚类处理
                ClusterButtonPushed(app, event);
                if isempty(app.ClusterCenters), return; end % 如果分类失败，则停止执行
                app.Lamp_cluster.Color = 'green';
        
                % 5. 分割处理
                SegmentButtonPushed(app, event);
                if isempty(app.SegmentedLabels), return; end % 如果分类失败，则停止执行
                app.Lamp_seg.Color = 'green';

            catch ME
                % 捕获错误并显示
                errordlg(ME.message, '错误');
        
                % 设置信号灯为灰色
                app.Lamp_preprocess.Color = [0.8 0.8 0.8];
                app.Lamp_layer.Color = [0.8 0.8 0.8];
                app.Lamp_cluster.Color = [0.8 0.8 0.8];
                app.Lamp_seg.Color = [0.8 0.8 0.8];
            end
        end

        % Button pushed function: ChangeDirButton
        function ChangeDirButtonPushed(app, event)
            % 检查 app.work_dir 是否为空
            if isempty(app.work_dir) || ~isfolder(app.work_dir)
                startPath = char(pwd);
            else
                startPath = char(app.work_dir);
            end

             % 打开文件夹选择对话框
            folderPath = uigetdir(startPath, '选择文件夹');
        
            % 如果用户选择了文件，则将文件路径显示在文本框中
            if ischar(folderPath) && exist(folderPath, 'dir')
                app.work_dir = folderPath;

                app.TextArea_workDir.Value = app.work_dir;

                % 强制刷新UI组件,重新绘制界面图形和更新文本框内容
                drawnow;
            else
                errordlg('用户取消文件选择或所选文件夹不存在', '错误');
                return;
            end
        end

        % Button pushed function: OpenInResourceWindow
        function OpenInResourceWindowButtonPushed(app, event)
            % 在 Windows 文件资源管理器中打开路径
            openPath = app.TextArea_workDir.Value;
            try
                winopen(char(openPath));
            catch
                warning('无法打开文件资源管理器，请检查路径是否有效。');
            end
        end

        % Button pushed function: LoadCornDataButton
        function LoadCornDataButtonPushed(app, event)
            % 初始化信号灯为灰色
            app.Lamp_load_2.Color = [0.8 0.8 0.8];
            % 检查是否需要加载新数据
            needLoad = false;

            try
                if isempty(app.plantsPoints)
                    needLoad = true;
                else
                    choice = questdlg('检测到已有缓存数据，是否加载新数据？', ...
                      '数据加载提示', ...
                      '加载新数据', '使用现有数据', '使用现有数据');
                    if strcmp(choice, '加载新数据')
                        needLoad = true;
                    elseif strcmp(choice, '使用现有数据')
                        uialert(app.UIFigure, '使用已加载的植株点云数据。', '数据加载提示', 'Icon', 'info');
                    else
                        return;  % 用户关闭了对话框
                    end
                end

                % 执行加载逻辑
                if needLoad
                    fullFilePath = app.openFile();  % 可自定义仅允许 PLY 文件
                    if ~exist(fullFilePath, 'file')
                        errordlg('用户取消文件选择或所选文件不存在', '错误');
                        return;
                    end
                
                    pts = pcread(fullFilePath).Location;
                    if isempty(pts)
                        errordlg('无效的点云数据', '错误');
                        return;
                    end
                
                    app.plantsPoints = pts;
                    app.TextArea_workDir.Value = app.work_dir;
                    drawnow;
                end

                % 可视化
                % 检查坐标轴是否有效
                if isvalid(app.Axes_RawPlants)
                    scatter3(app.Axes_RawPlants, app.plantsPoints(:, 1), app.plantsPoints(:, 2), app.plantsPoints(:, 3), 5, 'b.');
                    title(app.Axes_RawPlants, '原始点云');
                else
                    errordlg('坐标轴无效', '错误');
                    return;
                end

                app.Lamp_load_2.Color = 'green';
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_load_2.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: ProjectToZPlantButton
        function ProjectToZPlantButtonPushed(app, event)
            app.Lamp_project.Color = [0.8 0.8 0.8];

            try
                if isempty(app.plantsPoints)
                    errordlg('输入点云为空，请先执行数据加载！', '错误');
                    return; 
                end

                ptCloud = app.plantsPoints;
    
                % 提取 X 和 Y 坐标
                xy_points = ptCloud(:, 1:2);
                
                % 设置图像大小和范围（根据点云数据范围）
                x_range = [min(ptCloud(:, 1)), max(ptCloud(:, 1))];
                y_range = [min(ptCloud(:, 2)), max(ptCloud(:, 2))];
                z_range = [min(ptCloud(:, 3)), max(ptCloud(:, 3))];
                
                % 设置图像尺寸
                img_width = floor(x_range(2)*100); % 图像宽度
                img_height = floor(y_range(2)*100); % 图像高度

                app.img_range = {x_range, y_range, z_range, img_width, img_height};
                
                % 创建一张空白图像
                projected_img = zeros(img_height, img_width, 3, 'uint8');
                
                % 将点云投影到图像上
                for i = 1:size(xy_points, 1)
                    % 归一化坐标到图像尺寸
                    x_pixel = round((xy_points(i, 1) - x_range(1)) / (x_range(2) - x_range(1)) * (img_width - 1)) + 1;
                    y_pixel = round((xy_points(i, 2) - y_range(1)) / (y_range(2) - y_range(1)) * (img_height - 1)) + 1;
                   
                    % 确保坐标在有效范围内
                    if x_pixel >= 1 && x_pixel <= img_width && y_pixel >= 1 && y_pixel <= img_height
                        projected_img(y_pixel, x_pixel, :) = 255; % 将点标记为白色
                    end
                end
                
                % 转换为灰度图像
                gray_img = rgb2gray(projected_img);
                binaryImg = imbinarize(gray_img, 0.1); % 手动阈值为0.5，可调整
                app.binaryXYImg = ~binaryImg; % 反转，使白色植株为前景

                if isvalid(app.Axes_ProjectPicture)
                    imshow(app.binaryXYImg, 'Parent', app.Axes_ProjectPicture);
                    title(app.Axes_ProjectPicture, 'Z轴负方向点云投影图');
                else
                    errordlg('坐标轴无效', '错误');
                    return;
                end

                app.Lamp_project.Color = "green";
                
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_project.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end

        end

        % Button pushed function: CannyBlurClusterButton
        function CannyBlurClusterButtonPushed(app, event)
            app.Lamp_canny_cluster.Color = [0.8 0.8 0.8];

            try
                se1 = [ % 手动定义的三角形结构元素（等腰直角三角形示例）
                    1 0;
                    0 1;
                    1 1
                ];
                se2 = [ % 手动定义的三角形结构元素（等腰直角三角形示例）
                    1 1;
                    1 0;
                    0 1
                ];
                % 对图像进行膨胀操作
                dilatedImage1 = imdilate(app.binaryXYImg, se1);
                dilatedImage2 = imdilate(app.binaryXYImg, se2);
                 
                % 叠加结果图像（使用逻辑或操作，因为对于二值图像，1+1=1）
                combinedImage = min(dilatedImage1, dilatedImage2);
                
                % 平滑预处理（高斯滤波）
                smoothImg = imgaussfilt(double(combinedImage), 2); % σ=1，调整平滑程度
                
                % 应用 Canny 边缘检测
                edges = edge(smoothImg, 'Canny', [0.2, 0.5]);
                
                % 应用形态学操作
                se = strel('disk', 1); % 创建一个圆形结构元素
                edges_dilated = imdilate(edges, se2); % 膨胀操作
                edges_closed = imclose(edges_dilated, se); % 闭合操作

                if isvalid(app.Axes_ClusterPicture)
                    imshow(edges_closed, 'Parent', app.Axes_ClusterPicture);
                    title(app.Axes_ClusterPicture, '点云投影图边界范围');
                else
                    errordlg('坐标轴无效', '错误');
                    return;
                end

                [app.plantClosedEdge, ~]  = bwboundaries(edges_closed, 'noholes');

                app.Lamp_canny_cluster.Color = "green";
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_canny_cluster.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Button pushed function: BoundingBoxButton
        function BoundingBoxButtonPushed(app, event)
            app.Lamp_bounding_box.Color = [0.8 0.8 0.8];
            app.Lamp_saveplants.Color = [0.8 0.8 0.8];

            if isempty(app.plantsPoints)
                errordlg('输入点云为空，请先执行数据加载！', '错误');
                return; 
            elseif isempty(app.plantClosedEdge)
                errordlg('植株边界范围为空，请先划定边界范围！', '错误');
                return; 
            end

            try
                ptCloud = app.plantsPoints;
                B = app.plantClosedEdge;
    
                targets = cell(1, length(B)); % 初始化目标存储
                targets_info = zeros(length(B), 7); % 存储目标的坐标和索引
    
                [x_range, y_range, z_range, img_width, img_height] = app.img_range{:};
                
                % 绘制每个边界框
                for k = 1:length(B)
                    boundary = B{k};
                    
                    % 计算边界框的最小和最大坐标
                    min_x_pixel = min(boundary(:, 2));
                    max_x_pixel = max(boundary(:, 2));
                    min_y_pixel = min(boundary(:, 1));
                    max_y_pixel = max(boundary(:, 1));
                
                    % 反归一化坐标到点云坐标系
                    min_x = x_range(1) + (min_x_pixel - 1) / (img_width - 1) * (x_range(2) - x_range(1));
                    max_x = x_range(1) + (max_x_pixel - 1) / (img_width - 1) * (x_range(2) - x_range(1));
                    min_y = y_range(1) + (min_y_pixel - 1) / (img_height - 1) * (y_range(2) - y_range(1));
                    max_y = y_range(1) + (max_y_pixel - 1) / (img_height - 1) * (y_range(2) - y_range(1));
                    
                    % 存储目标的点云数据
                    target_points = ptCloud(ptCloud(:, 1) >= min_x & ptCloud(:, 1) <= max_x & ...
                                           ptCloud(:, 2) >= min_y & ptCloud(:, 2) <= max_y, :);
                    
                    % 获取每个目标的 XY 坐标和索引
                    xy_coords = mean(target_points(:, 1:2), 1); % 计算目标的平均 XY 坐标
                    targets_info(k, :) = [xy_coords, k, min_x, min_y, max_x, max_y]; % 存储坐标和目标索引
                    
                    targets{k} = target_points; % 保存目标点云
                end
    
                if isempty(targets)
                    errordlg('目标点云为空，请检查边界参数或点云数据', '错误');
                    return;
                end
    
                %% 反投影到三维点云并进行分割
                cla(app.Axes_BoundingBox);        % 清空坐标系，防止重叠
                hold(app.Axes_BoundingBox, 'on');
    
                % 创建三维图形
                scatter3(app.Axes_BoundingBox, ...
                         ptCloud(:, 1), ptCloud(:, 2), ptCloud(:, 3), 5, 'b', 'filled');    % 蓝色填充点，大小5
                
                % 边界框的12条边
                edges = [
                    1, 2; 1, 3; 1, 5;
                    2, 4; 2, 6;
                    3, 4; 3, 7;
                    4, 8;
                    5, 6; 5, 7;
                    6, 8;
                    7, 8
                ];
                
                % 通过排序，确定数据分组和组内顺序标签
                groupSize = 4;
                % 调用外部排序函数实现分组
                [groupSorted_targets, groupNums, others] = app.seg_box(targets_info, groupSize);
                
                for i = 1:groupNums+1
                    % 每个分组顺序读取4个标签的数据
                    for j = 1:groupSize
                        % 生成文件名
                        object_name = sprintf('%d-%d', i, j);

                        app.objects_name{end+1} = object_name;
                        
                        % 获取当前第 i 组的第 j 个数据
                        if (i == (groupNums + 1)) && (j > others)
                            break
                        else
                            targetIdx = groupSorted_targets((i-1)*groupSize + j, 3);
                        end
                        current_targets = targets{targetIdx};
                        [min_x, min_y, max_x, max_y] = deal(targets_info(targetIdx, 4), targets_info(targetIdx, 5), targets_info(targetIdx, 6), targets_info(targetIdx, 7));
                        
                        % 定义边界框的八个顶点
                        vertices = [
                            min_x, min_y, z_range(1);
                            min_x, min_y, z_range(2);
                            min_x, max_y, z_range(1);
                            min_x, max_y, z_range(2);
                            max_x, min_y, z_range(1);
                            max_x, min_y, z_range(2);
                            max_x, max_y, z_range(1);
                            max_x, max_y, z_range(2)
                        ];
                        
                        % 计算标签显示位置
                        avg_x = min(current_targets(:, 1)) + 0.1;
                        avg_y = mean(current_targets(:, 2)) + 0.2;
                        avg_z = max(current_targets(:, 3)) + 0.2;
                        
                        % 在三维图形中绘制线段
                        for s = 1:size(edges, 1)
                            line(app.Axes_BoundingBox, ...
                                [vertices(edges(s, 1), 1), vertices(edges(s, 2), 1)], ...
                                [vertices(edges(s, 1), 2), vertices(edges(s, 2), 2)], ...
                                [vertices(edges(s, 1), 3), vertices(edges(s, 2), 3)], 'Color', rand(1, 3), 'LineWidth', 2);
                        end    
                        % 显示标签信息
                        text(app.Axes_BoundingBox, ...
                            avg_x, avg_y, avg_z, object_name, ...
                            'Color', 'black', 'FontSize', 18, 'FontWeight', 'bold');

                        if strcmp(app.segSwitch.Value, "On")
                            output_folder = fullfile(app.TextArea_workDir.Value{1}, 'seg');
                            if ~exist(output_folder, 'dir')
                                mkdir(output_folder);
                            end

                            % 创建点云对象并保存为 PLY 文件
                            pc = pointCloud(current_targets);
                            filePath = fullfile(string(output_folder), string(object_name));  % 强制转成 char
                            disp(filePath);
                            pcwrite(pc, filePath, 'Encoding', 'ascii');
                        end
    
                    end
                end
                
                title(app.Axes_BoundingBox, ...
                      [num2str(length(targets)), ' 株玉米包围框分割实例图']);
    
                view(app.Axes_BoundingBox, 3);      % 设为 3D 视角
                hold(app.Axes_BoundingBox, 'off');  % 关闭保持模式
    
                app.Lamp_bounding_box.Color = 'green';

                if strcmp(app.segSwitch.Value, "On")
                    app.Lamp_saveplants.Color = "green";
                    message = sprintf('分割完成的所有植株已保存至:\n%s', fullfile(output_folder));
                else
                    message = sprintf('所有植株已分割完成，但未保存！\n若需保存请打开保存开关并重新执行最后一步！');
                end
                uialert(app.UIFigure, message, '提示', 'Icon', 'info');
            catch ME
                errordlg(ME.message, '错误');
                app.Lamp_bounding_box.Color = [0.8 0.8 0.8];
                app.Lamp_saveplants.Color = [0.8 0.8 0.8]; % 将信号灯设置为灰色
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            if ~isempty(app.objects_name)
                % 仅当app.objects_name不为空时才更新下拉菜单选项
                currentValue = app.DropDown.Value; % 保存当前选中的值
                app.DropDown.Items = [{'None'} app.objects_name];
                app.DropDown.Value = currentValue; % 恢复之前选中的值
            end
        end

        % Button pushed function: LoadSingleCornPointsButton
        function LoadSingleCornPointsButtonPushed(app, event)
            selectedObject = app.DropDown.Value;
            if strcmp(selectedObject, 'None')
                message = sprintf('植株标签信息为空，前置任务未执行！\n请选择打开单株玉米点云文件或取消后执行前置任务？');
                choice = questdlg(message, ...      % 提示信息
                                  '操作确认', ...    % 标题
                                  '打开', '取消', '取消');   % 按钮选项（默认'取消'）
                switch choice
                    case '打开'
                        fullFilePath = app.openFile();   % 调用属性函数打开点云文件
                    case '取消'
                        return;
                end                
            else
                % 加载缓存数据
                fileName = [selectedObject '.ply'];
                fullFilePath = fullfile(app.work_dir, 'seg', fileName);                
            end

            % 检查文件是否存在
            if ~exist(fullFilePath, 'file')
                errordlg('用户取消文件选择或所选文件不存在', '错误');
                return;
            end

            spPoints = pcread(fullFilePath).Location;
            app.LoadSingleCornPointsButton.UserData = {fullFilePath, spPoints};

            if isempty(spPoints)
                errordlg('无效的点云数据', '错误');
                return;
            end

            % 可视化
            % 检查坐标轴是否有效
            if isvalid(app.Axes_RawPlant)
                scatter3(app.Axes_RawPlant, spPoints(:, 1), spPoints(:, 2), spPoints(:, 3), 15, 'b.');
                title(app.Axes_RawPlant, fullFilePath, 'Interpreter', 'none'); % 关闭 TeX 或 LaTeX 解释器，防止对特殊字符的解析
                axis(app.Axes_RawPlant, 'equal');
            else
                errordlg('坐标轴无效', '错误');
                return;
            end
        end

        % Button pushed function: CalculateSkelonButton
        function CalculateSkelonButtonPushed(app, event)
            % 检查前置条件
            userData = app.LoadSingleCornPointsButton.UserData;
            if isempty(userData)
                errordlg('植株点云未定义，请先加载植株点云！', '错误');
                return;
            end
            % 创建可更新的进度对话框
            d = uiprogressdlg(app.UIFigure, ...
                'Title', '请稍候', ...
                'Message', '正在准备数据...', ...
                'Cancelable', 'off');
        
            % Step 0: 读取点云数据
            spPoints = userData{2};
            Points = double(spPoints);
            pts = Points(:, 1:3); 
            npts = size(pts, 1);
        
            % 更新进度条：数据准备完成
            d.Value = 0.1;
            d.Message = '正在计算边界框和直径...';
        
            % Step 1: 边界框与直径
            bbox = [min(pts(:,1)), min(pts(:,2)), min(pts(:,3)), ...
                    max(pts(:,1)), max(pts(:,2)), max(pts(:,3))];
            rs = bbox(4:6)-bbox(1:3);
            diameter = sqrt(dot(rs, rs));
        
            pause(0.2);  % 模拟计算时间，提升进度感
            d.Value = 0.25;
            d.Message = '正在构建点的邻域...';
        
            % Step 2: 构建1-ring邻域
            MIN_NEIGHBOR_NUM = 8;
            MAX_NEIGHBOR_NUM = 30;
            k_knn = max(MIN_NEIGHBOR_NUM, round(npts * 0.012));
            k_knn = min(k_knn, MAX_NEIGHBOR_NUM);
            rings = app.compute_point_point_ring(pts, k_knn, []);
        
            pause(0.2);
            d.Value = 0.4;
            d.Message = '正在初始化权重和拉普拉斯矩阵...';
        
            % Step 3: 拉普拉斯准备
            ms = app.one_ring_size(pts, rings, 2);
            WL = 1.0 / (5.0 * mean(ms));
            WH = ones(npts, 1) * 1;
        
            W = app.compute_point_weight_dcp(pts, rings);
            L = diag(sum(W, 2)) - W;
        
            pause(0.2);
            d.Value = 0.6;
            d.Message = '正在求解拉普拉斯约束系统...';
        
            % Step 4: 求解线性系统
            A = [L .* WL; sparse(1:npts, 1:npts, WH)];
            b = [zeros(npts, 3); sparse(1:npts, 1:npts, WH) * pts];
            cpts = A \ b;

            sample_radius = diameter * 0.02;
            [spls, spls_adj, ~] = app.rosa_lineextract(cpts, rings, sample_radius, 1);

            app.CalculateSkelonButton.UserData = {spls, spls_adj};
        
            pause(0.3);
            d.Value = 0.85;
            d.Message = '正在绘制骨架点云...';
        
            % Step 5: 绘制结果            
            scatter3(app.Axes_plantSkelon, spls(:,1),spls(:,2),spls(:,3), 200, '.', 'MarkerEdgeColor', [1.0, .65, .35]);
            hold(app.Axes_plantSkelon, 'on');

            spls_adj(logical(eye(size(spls_adj)))) = 0;            
            edge_num = 0;
            for i=1:(size(spls_adj, 1) - 1)
                for j=(i+1):size(spls_adj,2)
                    if(spls_adj(i,j)>0)
                        idx = [i;j];
                        line(app.Axes_plantSkelon, spls(idx,1),spls(idx,2),spls(idx,3), 'LineWidth', 2, 'Color', [1, .0, .0]);
                        edge_num = edge_num +1;
                    end
                end
            end
            
            titleName = sprintf("Corn Plant Skelon with %d points and %d edges", size(spls, 1), size(spls_adj, 1));
            title(app.Axes_plantSkelon, titleName);
            axis(app.Axes_plantSkelon, 'equal');
            hold(app.Axes_plantSkelon, 'off');
        
            pause(0.2);
            d.Value = 1.0;
            d.Message = '完成！';
        
            pause(0.5);
            close(d);  % 关闭进度条
        end

        % Button pushed function: ClassifySkelonButton
        function ClassifySkelonButtonPushed(app, event)
            userDataSkelon = app.CalculateSkelonButton.UserData;
            if isempty(userDataSkelon)
                errordlg('骨架点云未定义，请先完成骨架计算', '错误');
                return;
            end

            sorted_points = userDataSkelon{1};
            n_points = size(sorted_points, 1);
            spls_adj_filtered = userDataSkelon{2};

            % 验证输入一致性
            if size(spls_adj_filtered, 1) ~= n_points || size(spls_adj_filtered, 2) ~= n_points
                errordlg('邻接矩阵的维度必须与点的数量相匹配。', '错误');
                return;
            end

            %% 2. 预处理邻接矩阵并提取连接关系，同时计算点的度
            spls_adj_filtered = double(spls_adj_filtered | spls_adj_filtered');  % 强制对称化（无向图假设），逻辑或操作，确保对称
            spls_adj_filtered = triu(spls_adj_filtered, 1);               % 提取上三角，避免重复边
            
            % 生成边列表和权重，及点的度
            connections = [];
            edge_weights = [];
            degree = zeros(n_points, 1); % 初始化度数组
            for i = 1:n_points
                for j = i+1:n_points
                    if spls_adj_filtered(i, j) == 1
                        dist = norm(sorted_points(i, :) - sorted_points(j, :));
                        connections(end+1, :) = [i, j];
                        edge_weights(end+1) = dist;
                        % 同步更新度
                        degree(i) = degree(i) + 1;
                        degree(j) = degree(j) + 1;
                    end
                end
            end
            
            %% 对所有边应用 MST，确保连通性并移除闭环
            G = graph(connections(:, 1), connections(:, 2), edge_weights);
            T = minspantree(G);
            connections = [T.Edges.EndNodes(:, 1), T.Edges.EndNodes(:, 2)];

            %% 3. 基于邻接关系判断点的类型
            point_types = zeros(n_points, 1);
            for i = 1:n_points
                if degree(i) == 1
                    point_types(i) = 1; % 端点
                elseif degree(i) == 2
                    % 检查邻居的度
                    neighbors = connections(any(connections == i, 2), :);
                    neighbors = unique(neighbors(neighbors ~= i));
                    neighbor_degrees = degree(neighbors);
                    if all(neighbor_degrees <= 2)
                        point_types(i) = 2; % 中间点（邻居没有分支）
                    else
                        point_types(i) = 3; % 连接点（邻居中有分支）
                    end
                elseif degree(i) > 2
                    point_types(i) = 3; % 连接点
                end
            end

            app.ClassifySkelonButton.UserData = {edge_weights, point_types};
            
            %% 4. 可视化点云及连接关系
            % 清空旧图
            cla(app.Axes_plantSkelon_types);
            hold(app.Axes_plantSkelon_types, 'on');

            scatter3(app.Axes_plantSkelon_types, sorted_points(:, 1), sorted_points(:, 2), sorted_points(:, 3), 30, point_types, 'filled');           
            if sum(~point_types)
                colormap(app.Axes_plantSkelon_types, [0.5 0.5 0.5; 1 0.5 0; 0.5 0 0.5; 1 1 0]);
                colorbar(app.Axes_plantSkelon_types, 'Ticks', [0, 1, 2, 3], 'TickLabels', {'孤立点', '端点', '中间点', '连接点'});
            else
                colormap(app.Axes_plantSkelon_types, [1 0 0; 0 1 0; 0 0 1]);
                colorbar(app.Axes_plantSkelon_types, 'Ticks', [1, 2, 3], 'TickLabels', {'端点', '中间点', '连接点'});
            end

            for i = 1:size(connections, 1)
                start_point = sorted_points(connections(i, 1), :);
                end_point = sorted_points(connections(i, 2), :);
                plot3(app.Axes_plantSkelon_types, ...
                    [start_point(1), end_point(1)], ...
                    [start_point(2), end_point(2)], ...
                    [start_point(3), end_point(3)], 'r-', 'LineWidth', 2);
            end

            title(app.Axes_plantSkelon_types, 'Corn Skeleton with Connections');
            view(app.Axes_plantSkelon_types, -45, 5);
            axis(app.Axes_plantSkelon_types, 'equal');           % 保持坐标比例
            axis(app.Axes_plantSkelon_types, 'tight');           % 自动收缩到数据范围
            hold(app.Axes_plantSkelon_types, 'off');
        end

        % Button pushed function: ClassifyPlantPointsButton
        function ClassifyPlantPointsButtonPushed(app, event)
            userDataRawPoints = app.LoadSingleCornPointsButton.UserData;
            userDataSkelon = app.CalculateSkelonButton.UserData;
            userDataPointsType = app.ClassifySkelonButton.UserData;
            if isempty(userDataRawPoints)
                errordlg('原始点云未加载，请先完成第一步点云数据加载！', '错误');
                return;
            elseif isempty(userDataSkelon)
                errordlg('骨架点云未定义，请先完成骨架计算', '错误');
                return;
            elseif isempty(userDataPointsType)
                errordlg('骨架点未进行分类，请先完成骨架点分类', '错误');
                return;
            end
            
            raw_points = userDataRawPoints{2};
            sorted_points = userDataSkelon{1};
            m_points = size(raw_points, 1);

            %% 设置距离阈值（动态估计骨架点平均间距）
            skeleton_dists = userDataPointsType{1}; % 使用骨架边的距离
            dist_threshold = mean(skeleton_dists) + std(skeleton_dists)*1.8;

            point_types = userDataPointsType{2};
            
            % 最近邻搜索：将原始点分配到骨架点
            [idx, dists] = knnsearch(sorted_points, raw_points, 'K', 1); % 每个原始点找最近的骨架点
            raw_point_types = zeros(m_points, 1); % 0 表示未分类
            for i = 1:m_points
                if dists(i) < dist_threshold
                    raw_point_types(i) = point_types(idx(i)); % 继承最近骨架点的类型
                end
            end

            scatter3(app.Axes_plant_with_types, raw_points(:, 1), raw_points(:, 2), raw_points(:, 3), 5, raw_point_types, 'filled');
            if sum(~point_types)
                colormap(app.Axes_plant_with_types, [0.5 0.5 0.5; 1 0.5 0; 0 1 0; 0.5 0 0.5]);
                colorbar(app.Axes_plant_with_types, 'Ticks', [0, 1, 2, 3], 'TickLabels', {'孤立点', '端点', '中间点', '连接点'});
            else
                colormap(app.Axes_plant_with_types, [1 0 0; 0 1 0; 0 0 1]);
                colorbar(app.Axes_plant_with_types, 'Ticks', [1, 2, 3], 'TickLabels', {'端点', '中间点', '连接点'});
            end
            title(app.Axes_plant_with_types, 'Classified Raw Plant Points');
            axis(app.Axes_plant_with_types, 'equal');           % 保持坐标比例
            axis(app.Axes_plant_with_types, 'tight');           % 自动收缩到数据范围
        end

        % Button pushed function: SaveSkelonButton
        function SaveSkelonButtonPushed(app, event)
            pathname = char(app.work_dir);
            userDataLoad = app.LoadSingleCornPointsButton.UserData;
            userDataSkelon = app.CalculateSkelonButton.UserData;

            % 检查前置条件
            if isempty(userDataLoad) || isempty(userDataSkelon)
                errordlg('骨架点云未定义，请先完成骨架计算', '错误');
                return;
            end

            [~, fName, fExt] = fileparts(userDataLoad{1});
            spls = userDataSkelon{1};

            savePath = fullfile(pathname, [fName '-skelon-points' fExt]);

            [file, path] = uiputfile('*.ply', '保存植株点云', savePath);
            if isequal(file, 0) || isequal(path, 0)
                return;
            else
                validIdx = all(~isnan(spls), 2);
                % 构建过滤后的点云
                spls_filtered = pointCloud(spls(validIdx, :));                
                % 构建同步过滤后的邻接矩阵
                spls_adj = userDataSkelon{2};
                spls_adj_filtered = spls_adj(validIdx, validIdx);

                pcwrite(spls_filtered, fullfile(path, file));
                save([path fName '-skelon-points-connectivity.mat'], 'spls_adj_filtered');
            end

            message = sprintf('植株骨架点云文件和连接关系文件已保存至：\n%s', path);
            choice = questdlg(message, ...      % 提示信息
                                  '操作确认', ...    % 标题
                                  '打开文件夹', '确定', '确定');   % 按钮选项（默认'取消'）
            switch choice
                case '打开文件夹'
                    try
                        winopen(char(path));
                    catch
                        warning('无法打开文件资源管理器，请检查路径是否有效。');
                    end
                case '取消'
                    return;
            end 
        end

        % Button pushed function: UserEditSkelonButton
        function UserEditSkelonButtonPushed(app, event)
            % 模拟准备的三维点云数据
            pathname = 'D:\workspaces\matlab\skeleonExtra\';
            filename = '3-3-skelon-points_with_types.ply';
            
            ptCloud = pcread([pathname filename]);
            points = ptCloud.Location;
            pointTypes = ptCloud.Intensity;
        
            % 启动 app2 并传入数据（支持回调返回）
            userEditSkelon(points, pointTypes, @(editedPoints, editedTypes) updateCallback(app, editedPoints, editedTypes));
        end

        % Button pushed function: Button_LoadPlantSegInfo
        function Button_LoadPlantSegInfoPushed(app, event)
            load('E:\Datasets\3d\20241012\seg-未优化\2-2_plantInfo.mat', 'plantPts', 'plantRegions');
            [points, OBBs, OrgPoints, PhenoTraits, OrganTypes] = app.PhenotypicTrait(plantPts, plantRegions);

            app.Button_LoadPlantSegInfo.UserData = {points, OBBs, OrgPoints, PhenoTraits, OrganTypes};

            cla(app.UIAxes_PlantInfo);
            hold(app.UIAxes_PlantInfo, 'on');
            
            numOrgans = length(OrgPoints);
            
            % DropDown 菜单
            app.organNames = {};
            for i = 1:numOrgans-1
                if i == 1
                    app.organNames{i} = sprintf('%s', OrganTypes{i+1});
                else
                    app.organNames{i} = sprintf('%s-%d', OrganTypes{i+1}, i-1);
                end
            end


            color = MyGS.MYCOLOR;
            for i = 2:numOrgans
                path = OrgPoints{i};  % 对应骨架路径
                if isempty(path)
                    continue;
                end
            
                basePoint = path(1, :);
                if i == 2
                    % 主茎序号1，标记在起点，稍作偏移
                    text(app.UIAxes_PlantInfo, basePoint(1)-0.03, basePoint(2), basePoint(3)-0.1, 'stem', 'Color', 'black', 'FontSize', 25, 'FontWeight', 'bold');
                    scatter3(app.UIAxes_PlantInfo, path(:,1), path(:,2), path(:,3), 6, color(end, :), 'filled');
                else
                    % 叶片编号从2开始，标记在骨架中点位置
                    scatter3(app.UIAxes_PlantInfo, path(:,1), path(:,2), path(:,3), 6, color(i, :), 'filled');
                    text(app.UIAxes_PlantInfo, basePoint(1)+0.05, basePoint(2)-0.01, basePoint(3)+0.04, ['leaf-' num2str(i)-2], 'Color', 'black', 'FontSize', 25);
                end
            end
            
            plantIdx = find(strcmp(OrganTypes, 'plant'));
            leafNum  = sum(strcmp(OrganTypes, 'leaf'));
            app.TextArea_plantOverview.Value = {
                sprintf('%6s : %.2f', '总点数', PhenoTraits(plantIdx, 9)), ...
                sprintf('%6s : %d', '叶片数', leafNum), ...
                sprintf('%6s : %.2f', '最大高度', PhenoTraits(plantIdx, 4)), ...
                sprintf('%6s : %.2f', '最大宽度', max(PhenoTraits(:,2))), ...
                sprintf('%6s : %.2f', '体积密度', PhenoTraits(plantIdx, 5)), ...
                sprintf('%6s : %.2f', '面积密度', PhenoTraits(plantIdx, 6)), ...
                sprintf('%6s : %.2f', '主轴倾斜角', PhenoTraits(plantIdx, 7)), ...
                sprintf('%6s : %.2f', '空间占用率', PhenoTraits(plantIdx, 8))
            };

        end

        % Clicked callback: DropDown_SelectOrgan
        function DropDown_SelectOrganClicked(app, event)
            dataCell = app.Button_LoadPlantSegInfo.UserData;
            if isempty(dataCell)
                errordlg('植株整体信息未加载，请先单击"加载植株数据"按钮进行加载！', '错误');
                return;
            end
            
            points = dataCell{1};
            OBBs = dataCell{2};
            OrgPoints = dataCell{3};
            PhenoTraits = dataCell{4};
            OrganTypes = dataCell{5};

            if ~isempty(app.organNames)
                % 仅当app.objects_name不为空时才更新下拉菜单选项
                currentValue = app.DropDown_SelectOrgan.Value; % 保存当前选中的值

                % 更新 Items
                newItems = [{'None'} app.organNames];
                app.DropDown_SelectOrgan.Items = newItems;

                if ismember(currentValue, newItems)
                    app.DropDown_SelectOrgan.Value = currentValue;
                else
                    app.DropDown_SelectOrgan.Value = 'None';
                end
            end

            cla(app.UIAxes_PlantInfo);
            scatter3(app.UIAxes_PlantInfo, points(:,1), points(:,2), points(:,3), 3, "magenta", 'filled');
            axis(app.UIAxes_PlantInfo, 'equal'); axis(app.UIAxes_PlantInfo, 'off'); hold(app.UIAxes_PlantInfo, 'on');

            if strcmp(currentValue, "None")
                app.TextArea_organView.Value = {'Select an organ from the dropdown.'};
                return;
            end

            % 获取下拉选择的器官索引
            sel_idx = find(strcmp(app.DropDown_SelectOrgan.Items, app.DropDown_SelectOrgan.Value));
            
            % 设置颜色
            color = 'g';
            if strcmp(OrganTypes{sel_idx}, 'stem')
                color = 'b';
            elseif strcmp(OrganTypes{sel_idx}, 'plant')
                color = 'r';
            end
            
            % 绘制对应 OBB
            app.draw_obb_box(OBBs{sel_idx}.Min, OBBs{sel_idx}.Max, ...
                OBBs{sel_idx}.dir1, OBBs{sel_idx}.dir2, OBBs{sel_idx}.dir3, ...
                OBBs{sel_idx}.center, 'black');
            
            % 高亮选中器官点
            sel_pts = OrgPoints{sel_idx};
            scatter3(app.UIAxes_PlantInfo, sel_pts(:,1), sel_pts(:,2), sel_pts(:,3), 8, color, 'filled');
            
            % 更新参数文本信息
            info = PhenoTraits(sel_idx,:);
            app.TextArea_organView.Value = {
                sprintf('%8s : %s', '器官类型', upper(OrganTypes{sel_idx})), ...
                sprintf('%8s : %.3f', 'OBB主轴长度', info(1)), ...
                sprintf('%8s : %.3f', 'OBB三轴长度', info(2)), ...
                sprintf('%8s : %.3f', '体积密度',  info(3)), ...
                sprintf('%8s : %.3f', '面积密度',  info(4)), ...
                sprintf('%8s : %.3f', '  叶位高',  info(5)), ...
                sprintf('%8s : %.3f', '  基尖距',  info(6)), ...
                sprintf('%8s : %.3f', '  叶倾角', info(7)), ...
                sprintf('%8s : %.3f', '  叶仰角',  info(8)), ...
                sprintf('%8s : %.3f', '叶方位角',  info(9))
            };
            
        end

        % Menu selected function: Menu_APP_Exit
        function Menu_APP_ExitSelected(app, event)
            delete(app); % 关闭 App
        end

        % Menu selected function: Menu_Clear_Tab_figures
        function Menu_Clear_Tab_figuresSelected(app, event)
            % 获取当前选中的选项卡
            currentTab = app.TabGroup.SelectedTab;
        
            % 查找当前选项卡中的所有坐标区
            axesInCurrentTab = findobj(currentTab, 'Type', 'axes');
        
            % 清除所有坐标区的内容
            for i = 1:length(axesInCurrentTab)
                cla(axesInCurrentTab(i), 'reset');
            end

            % 查找所有状态指示灯（Lamp 组件）
            lampObjects = findobj(currentTab, 'Type', 'uilamp');
        
            % 将所有状态指示灯设置为灰色
            for i = 1:length(lampObjects)
                lampObjects(i).Color = [0.8 0.8 0.8];
            end
        end

        % Menu selected function: Menu_Clear_all_variables
        function Menu_Clear_all_variablesSelected(app, event)
            % 获取 App 的工作区字段
            props = fieldnames(app);
            
            % 遍历字段并清除
            for i = 1:length(props)
                % 获取当前属性的值
                value = app.(props{i});
                
                % 仅清除非 UI 组件的变量
                if ~isa(value, 'matlab.ui.control.UIControl') && ...
                   ~isa(value, 'matlab.ui.Figure') && ...
                   ~isa(value, 'matlab.ui.container.Menu') && ...
                   ~isa(value, 'matlab.ui.container.Panel') && ...
                   ~isa(value, 'matlab.graphics.Graphics')
                    disp(app.(props{i}));
                    app.(props{i}) = [];
                end
            end
            
            % 可选：刷新 UI 或者给用户提示
            uialert(app.UIFigure, '所有变量已清除！', '提示', 'Icon', 'info');
        end

        % Menu selected function: Menu_APP_Info
        function Menu_APP_InfoSelected(app, event)
            % APP 信息
            appName = 'Maize3D-SegLab for Phenotype Segmentation and Labeling';
            appVersion = 'V1.0';
            appAuthor = 'RY';
            appDescription = 'This software is used to achieve integrated processing of corn plant point cloud segmentation from the field to individual plants, and then to organs such as stems and leaves!';
        
            % 创建信息字符串
            infoStr = sprintf('软件名称：%s\n当前版本：%s\n 开 发 者 ：%s\n功能描述：%s', ...
                appName, appVersion, appAuthor, appDescription);
        
            % 显示信息弹窗
            msgbox(infoStr, '关于 APP', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu_File = uimenu(app.UIFigure);
            app.Menu_File.Text = '文件';
            app.Menu_APP_Exit = uimenu(app.Menu_File);
            app.Menu_APP_Exit.MenuSelectedFcn = createCallbackFcn(app, @Menu_APP_ExitSelected, true);
            app.Menu_APP_Exit.Text = '退出';
            app.Menu_Reset = uimenu(app.UIFigure);
            app.Menu_Reset.Text = '重置';
            app.Menu_Clear_Tab_figures = uimenu(app.Menu_Reset);
            app.Menu_Clear_Tab_figures.MenuSelectedFcn = createCallbackFcn(app, @Menu_Clear_Tab_figuresSelected, true);
            app.Menu_Clear_Tab_figures.Text = '清除当前界面图像';
            app.Menu_Clear_all_variables = uimenu(app.Menu_Reset);
            app.Menu_Clear_all_variables.MenuSelectedFcn = createCallbackFcn(app, @Menu_Clear_all_variablesSelected, true);
            app.Menu_Clear_all_variables.Text = '清除所有变量';
            app.Menu_About = uimenu(app.UIFigure);
            app.Menu_About.Text = '关于';
            app.Menu_APP_Info = uimenu(app.Menu_About);
            app.Menu_APP_Info.MenuSelectedFcn = createCallbackFcn(app, @Menu_APP_InfoSelected, true);
            app.Menu_APP_Info.Text = 'Info';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlantGroundSeg = uitab(app.TabGroup);
            app.PlantGroundSeg.Title = '植株与地面分割';
            app.FunctionPanel = uipanel(app.PlantGroundSeg);
            app.FunctionPanel.Title = 'FunctionPanel';
            app.OpenFileButton = uibutton(app.FunctionPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'OpenFileButton';
            app.PreprocessButton = uibutton(app.FunctionPanel, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'PreprocessButton';
            app.LayerButton = uibutton(app.FunctionPanel, 'push');
            app.LayerButton.ButtonPushedFcn = createCallbackFcn(app, @LayerButtonPushed, true);
            app.LayerButton.Text = 'LayerButton';
            app.ClusterButton = uibutton(app.FunctionPanel, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'ClusterButton';
            app.SegmentButton = uibutton(app.FunctionPanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'SegmentButton';
            app.SavePlantsButton = uibutton(app.FunctionPanel, 'push');
            app.SavePlantsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlantsButtonPushed, true);
            app.SavePlantsButton.Text = 'SavePlantButton';
            app.StatusLabel = uilabel(app.FunctionPanel);
            app.StatusLabel.Text = 'Status';
            app.Lamp_load = uilamp(app.FunctionPanel);
            app.Lamp_preprocess = uilamp(app.FunctionPanel);
            app.Lamp_layer = uilamp(app.FunctionPanel);
            app.Lamp_cluster = uilamp(app.FunctionPanel);
            app.Lamp_seg = uilamp(app.FunctionPanel);
            app.Lamp_save = uilamp(app.FunctionPanel);
            app.OneKeyProcessButton = uibutton(app.FunctionPanel, 'push');
            app.OneKeyProcessButton.ButtonPushedFcn = createCallbackFcn(app, @OneKeyProcessButtonPushed, true);
            app.OneKeyProcessButton.Text = 'OneKeyProcess';
            app.VisualizationPanel = uipanel(app.PlantGroundSeg);
            app.VisualizationPanel.Title = 'VisualizationPanel';
            app.GridLayout = uigridlayout(app.VisualizationPanel);
            app.RawAxes = uiaxes(app.GridLayout);
            title(app.RawAxes, 'Title')
            xlabel(app.RawAxes, 'X')
            ylabel(app.RawAxes, 'Y')
            zlabel(app.RawAxes, 'Z')
            app.PreprocessedAxes = uiaxes(app.GridLayout);
            title(app.PreprocessedAxes, 'Title')
            xlabel(app.PreprocessedAxes, 'X')
            ylabel(app.PreprocessedAxes, 'Y')
            zlabel(app.PreprocessedAxes, 'Z')
            app.LayeredAxes = uiaxes(app.GridLayout);
            title(app.LayeredAxes, 'Title')
            xlabel(app.LayeredAxes, 'X')
            ylabel(app.LayeredAxes, 'Y')
            zlabel(app.LayeredAxes, 'Z')
            app.TargetLayerAxes = uiaxes(app.GridLayout);
            title(app.TargetLayerAxes, 'Title')
            xlabel(app.TargetLayerAxes, 'X')
            ylabel(app.TargetLayerAxes, 'Y')
            zlabel(app.TargetLayerAxes, 'Z')
            app.ClusteredAxes = uiaxes(app.GridLayout);
            title(app.ClusteredAxes, 'Title')
            xlabel(app.ClusteredAxes, 'X')
            ylabel(app.ClusteredAxes, 'Y')
            zlabel(app.ClusteredAxes, 'Z')
            app.SegmentedAxes = uiaxes(app.GridLayout);
            title(app.SegmentedAxes, 'Title')
            xlabel(app.SegmentedAxes, 'X')
            ylabel(app.SegmentedAxes, 'Y')
            zlabel(app.SegmentedAxes, 'Z')
            app.ParametersPanel = uipanel(app.PlantGroundSeg);
            app.ParametersPanel.Title = 'ParametersPanel';
            app.GridSizeEditFieldLabel = uilabel(app.ParametersPanel);
            app.GridSizeEditFieldLabel.Text = 'GridSize';
            app.GridSizeEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MeanKEditFieldLabel = uilabel(app.ParametersPanel);
            app.MeanKEditFieldLabel.Text = 'MeanK';
            app.MeanKEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.StdThreshLabel = uilabel(app.ParametersPanel);
            app.StdThreshLabel.Text = 'StdThresh';
            app.StdThreshEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LayerHeightLabel = uilabel(app.ParametersPanel);
            app.LayerHeightLabel.Text = 'LayerHeight';
            app.LayerHeightEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.EpsMultiplierLabel = uilabel(app.ParametersPanel);
            app.EpsMultiplierLabel.Text = 'EpsMultiplier';
            app.EpsMultiplierEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.HeightOffsetLabel = uilabel(app.ParametersPanel);
            app.HeightOffsetLabel.Text = 'HeightOffset';
            app.HeightOffsetEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.Cluster2Plant = uitab(app.TabGroup);
            app.Cluster2Plant.Title = '玉米群体至单株分割';
            app.FunctionPanel_2 = uipanel(app.Cluster2Plant);
            app.FunctionPanel_2.Title = 'FunctionPanel';
            app.LoadCornDataButton = uibutton(app.FunctionPanel_2, 'push');
            app.LoadCornDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCornDataButtonPushed, true);
            app.LoadCornDataButton.Text = 'LoadCornData';
            app.ProjectToZPlantButton = uibutton(app.FunctionPanel_2, 'push');
            app.ProjectToZPlantButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectToZPlantButtonPushed, true);
            app.ProjectToZPlantButton.Text = 'ProjectToZPlant';
            app.CannyBlurClusterButton = uibutton(app.FunctionPanel_2, 'push');
            app.CannyBlurClusterButton.ButtonPushedFcn = createCallbackFcn(app, @CannyBlurClusterButtonPushed, true);
            app.CannyBlurClusterButton.Text = 'CannyBlur+Cluster';
            app.BoundingBoxButton = uibutton(app.FunctionPanel_2, 'push');
            app.BoundingBoxButton.ButtonPushedFcn = createCallbackFcn(app, @BoundingBoxButtonPushed, true);
            app.BoundingBoxButton.Text = 'BoundingBox';
            app.StatusLabel_2 = uilabel(app.FunctionPanel_2);
            app.StatusLabel_2.Text = 'Status';
            app.Lamp_load_2 = uilamp(app.FunctionPanel_2);
            app.Lamp_project = uilamp(app.FunctionPanel_2);
            app.Lamp_canny_cluster = uilamp(app.FunctionPanel_2);
            app.Lamp_bounding_box = uilamp(app.FunctionPanel_2);
            app.Lamp_saveplants = uilamp(app.FunctionPanel_2);
            app.Label = uilabel(app.FunctionPanel_2);
            app.Label.Text = {'保存分割后的植株点云为单独文件'; '默认位置：./seg'};
            app.segSwitch = uiswitch(app.FunctionPanel_2, 'slider');
            app.VisualizationPanel_2 = uipanel(app.Cluster2Plant);
            app.VisualizationPanel_2.Title = 'VisualizationPanel';
            app.GridLayout_2 = uigridlayout(app.VisualizationPanel_2);
            app.Axes_RawPlants = uiaxes(app.GridLayout_2);
            title(app.Axes_RawPlants, 'Title')
            xlabel(app.Axes_RawPlants, 'X')
            ylabel(app.Axes_RawPlants, 'Y')
            zlabel(app.Axes_RawPlants, 'Z')
            app.Axes_ProjectPicture = uiaxes(app.GridLayout_2);
            title(app.Axes_ProjectPicture, 'Title')
            xlabel(app.Axes_ProjectPicture, 'X')
            ylabel(app.Axes_ProjectPicture, 'Y')
            zlabel(app.Axes_ProjectPicture, 'Z')
            app.Axes_ClusterPicture = uiaxes(app.GridLayout_2);
            title(app.Axes_ClusterPicture, 'Title')
            xlabel(app.Axes_ClusterPicture, 'X')
            ylabel(app.Axes_ClusterPicture, 'Y')
            zlabel(app.Axes_ClusterPicture, 'Z')
            app.Axes_BoundingBox = uiaxes(app.GridLayout_2);
            title(app.Axes_BoundingBox, 'Title')
            xlabel(app.Axes_BoundingBox, 'X')
            ylabel(app.Axes_BoundingBox, 'Y')
            zlabel(app.Axes_BoundingBox, 'Z')
            app.ParametersPanel_2 = uipanel(app.Cluster2Plant);
            app.ParametersPanel_2.Title = 'ParametersPanel';
            app.GridSizeEditField_2Label = uilabel(app.ParametersPanel_2);
            app.GridSizeEditField_2Label.Text = 'GridSize';
            app.GridSizeEditField_2 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.MeanKEditField_2Label = uilabel(app.ParametersPanel_2);
            app.MeanKEditField_2Label.Text = 'MeanK';
            app.MeanKEditField_2 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.Y4X603115Y14XLabel = uilabel(app.ParametersPanel_2);
            app.Y4X603115Y14XLabel.Text = {'说明：'; '1、默认使用种植较为规则的田间玉米植株作为输入数据，行数、列数固定；'; '2、示例数据采集自15垄（Y轴方向）田间玉米，每垄4株（X轴方向），共计60株。'; '3、输出植株文件名称的按照植株所在位置进行标签编号，自作向右1~15表示垄次序（Y轴），自后向前1~4表示植株次序（X轴）。'};
            app.Label_3 = uilabel(app.Cluster2Plant);
            app.Label_3.Text = '工作目录';
            app.TextArea_workDir = uitextarea(app.Cluster2Plant);
            app.TextArea_workDir.Editable = 'off';
            app.ChangeDirButton = uibutton(app.Cluster2Plant, 'push');
            app.ChangeDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDirButtonPushed, true);
            app.OpenInResourceWindow = uibutton(app.Cluster2Plant, 'push');
            app.OpenInResourceWindow.ButtonPushedFcn = createCallbackFcn(app, @OpenInResourceWindowButtonPushed, true);
            app.SteamLeafSeg = uitab(app.TabGroup);
            app.SteamLeafSeg.Title = '玉米植株茎叶分割';
            app.Panel = uipanel(app.SteamLeafSeg);
            app.Panel.Title = 'Panel';
            app.LoadSingleCornPointsButton = uibutton(app.Panel, 'push');
            app.LoadSingleCornPointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSingleCornPointsButtonPushed, true);
            app.LoadSingleCornPointsButton.Text = 'LoadSingleCornPoints';
            app.CalculateSkelonButton = uibutton(app.Panel, 'push');
            app.CalculateSkelonButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSkelonButtonPushed, true);
            app.CalculateSkelonButton.Text = 'CalculateSkelon';
            app.ClassifySkelonButton = uibutton(app.Panel, 'push');
            app.ClassifySkelonButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifySkelonButtonPushed, true);
            app.ClassifySkelonButton.Text = 'ClassifySkelon';
            app.ClassifyPlantPointsButton = uibutton(app.Panel, 'push');
            app.ClassifyPlantPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyPlantPointsButtonPushed, true);
            app.ClassifyPlantPointsButton.Text = 'ClassifyPlantPoints';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = {'选择植'; '株标签'};
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'None'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = 'None';
            app.Panel2 = uipanel(app.SteamLeafSeg);
            app.Panel2.Title = 'Panel2';
            app.SaveSkelonButton = uibutton(app.Panel2, 'push');
            app.SaveSkelonButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSkelonButtonPushed, true);
            app.SaveSkelonButton.Text = 'SaveSkelon';
            app.UserEditSkelonButton = uibutton(app.Panel2, 'push');
            app.UserEditSkelonButton.ButtonPushedFcn = createCallbackFcn(app, @UserEditSkelonButtonPushed, true);
            app.UserEditSkelonButton.Text = 'UserEditSkelon';
            app.Panel3 = uipanel(app.SteamLeafSeg);
            app.Panel3.Title = 'Panel3';
            app.GridLayout3 = uigridlayout(app.Panel3);
            app.Axes_RawPlant = uiaxes(app.GridLayout3);
            title(app.Axes_RawPlant, 'Title')
            xlabel(app.Axes_RawPlant, 'X')
            ylabel(app.Axes_RawPlant, 'Y')
            zlabel(app.Axes_RawPlant, 'Z')
            app.Axes_plantSkelon = uiaxes(app.GridLayout3);
            title(app.Axes_plantSkelon, 'Title')
            xlabel(app.Axes_plantSkelon, 'X')
            ylabel(app.Axes_plantSkelon, 'Y')
            zlabel(app.Axes_plantSkelon, 'Z')
            app.Axes_plantSkelon_types = uiaxes(app.GridLayout3);
            title(app.Axes_plantSkelon_types, 'Title')
            xlabel(app.Axes_plantSkelon_types, 'X')
            ylabel(app.Axes_plantSkelon_types, 'Y')
            zlabel(app.Axes_plantSkelon_types, 'Z')
            app.Axes_plant_with_types = uiaxes(app.GridLayout3);
            title(app.Axes_plant_with_types, 'Title')
            xlabel(app.Axes_plant_with_types, 'X')
            ylabel(app.Axes_plant_with_types, 'Y')
            zlabel(app.Axes_plant_with_types, 'Z')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '表型提取';
            app.GridLayout4 = uigridlayout(app.Tab);
            app.UIAxes_PlantInfo = uiaxes(app.GridLayout4);
            title(app.UIAxes_PlantInfo, '单株玉米表型参数可视化')
            xlabel(app.UIAxes_PlantInfo, 'X')
            ylabel(app.UIAxes_PlantInfo, 'Y')
            zlabel(app.UIAxes_PlantInfo, 'Z')
            app.Label_5 = uilabel(app.GridLayout4);
            app.Label_5.Text = '植株信息总览';
            app.Label_6 = uilabel(app.GridLayout4);
            app.Label_6.Text = '器官信息详情';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.DropDown_SelectOrgan = uidropdown(app.GridLayout5);
            app.DropDown_SelectOrgan.Items = {'单击选择器官'};
            app.DropDown_SelectOrgan.ClickedFcn = createCallbackFcn(app, @DropDown_SelectOrganClicked, true);
            app.DropDown_SelectOrgan.Value = '单击选择器官';
            app.Button_LoadPlantSegInfo = uibutton(app.GridLayout5, 'push');
            app.Button_LoadPlantSegInfo.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPlantSegInfoPushed, true);
            app.Button_LoadPlantSegInfo.Text = '加载植株数据';
            app.TextArea_plantOverview = uitextarea(app.GridLayout4);
            app.TextArea_organView = uitextarea(app.GridLayout4);
            app.OtherTools = uitab(app.TabGroup);
            app.OtherTools.Title = '其他';
            app.GridLayout2 = uigridlayout(app.OtherTools);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CornSeg2.png');
            app.HitMaize3DSegLabforPhenotypeSegmentationandLabelingVersion10 = uilabel(app.GridLayout2);
            app.HitMaize3DSegLabforPhenotypeSegmentationandLabelingVersion10.Interpreter = 'tex';
            app.HitMaize3DSegLabforPhenotypeSegmentationandLabelingVersion10.Text = '高通量玉米三维表型自动分割与标注平台（HiT-Maize3DPheno）';
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = {'A Platform for High-throughput Automatic Segmentation and Labeling in Maize 3D Phenotypic Analysis'; 'V1.0.1'};
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '发布日期：2025年08月18日';
            app.SR1553104Label = uilabel(app.GridLayout2);
            app.SR1553104Label.Text = '软著登记号：2025SR1553104';
            app.Windows1011Matlab2022bLabel = uilabel(app.GridLayout2);
            app.Windows1011Matlab2022bLabel.Text = '环境要求：Windows 10/11, Matlab 2022b';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantEarthSegApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end